classdef Calculate_SNR_nonfocal_tagdata_DTAG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SNRCalculatorUIFigure         matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        opendirectory                 matlab.ui.container.toolbar.PushTool
        openwavfile                   matlab.ui.container.toolbar.PushTool
        opennoisefile                 matlab.ui.container.toolbar.PushTool
        CalculateSNRButton            matlab.ui.control.Button
        SavecsvfileButton_2           matlab.ui.control.Button
        UITable2                      matlab.ui.control.Table
        SNRValueLogLabel              matlab.ui.control.Label
        CurrentwavfileEditField       matlab.ui.control.EditField
        CurrentwavfileEditFieldLabel  matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        Waveformplot                  matlab.ui.control.UIAxes
        SpectrogramPlot               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file % Description
        T % Description
        S % Description
        F % Description
        x_unfilt % Direction
        s_unfilt % Description
        plotlength % Description
        file3 % Description
        path % Description
        highfreq % Description
        starttime % Description
        timewindow % Description
        AudioData1   % First audio data
        Fs1         % Sampling rate of first audio
        AudioData2   % Second audio data
        Fs2         % Sampling rate of second audio
        stringList   % Cell array to store strings
        fileList   % Cell array to store filenames
        snrList    % Array to store SNR values
        folderPath % Directory to open files
        UIAxes % axes
    end 
    methods (Access = private)
        
        function [jth,ith]=do_SimpleDetection0(app,snr,tt,th, dt_max)  
        % function [jth,ith]=do_SimpleDetection0(snr,th, dt_max)
        % simple click detector
        %
        % thresholding
        ith=find(snr>th);
        %
        % detection pruning
        jth=ith;
        jj=1;
        for ii=2:length(ith)
        ki=ith(ii);
        kj=ith(jj);
        if tt(ki)-tt(kj)>dt_max
        jj=ii;
        continue
        else
        if snr(ki)>snr(kj) % actual snr value is larger
        % than old one
        jth(jj)=0; % remove old one
        jj=ii; % take the actual as old
        else
        jth(ii)=0; % remove actual one
        end
        end
        end
        
        %
        %reduce detection vector
        jth(jth==0)=[];
        end    

        function demo
        % Allow a line to have its own 'ButtonDownFcn' callback
        app.SpectrogramPlot.ButtonDownFcn = 'disp(''This executes'')';
        app.SpectrogramPlot.Tag = 'DoNotIgnore';
        h = pan;
        h.ButtonDownFilter = @mycallback;
        h.Enable = 'on';
        % Mouse click on the line
        end
    
        function [flag] = mycallback(obj,event_obj)
        % If the object tag is 'DoNotIgnore', then return true
    
        % Indicate what the target is
        disp(['Clicked ' obj.Type ' object'])
        objTag = obj.Tag;
        if strcmpi(objTag,'DoNotIgnore')
            flag = true;
        else
            flag = false;
        end
    end
        
    function startupFcn(app)
        app.UIAxes.PositionConstraint = 'innerposition';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: openwavfile
        function openwavfileClicked(app, event)
            [app.file, pathname] = uigetfile(fullfile(app.folderPath, '*.wav'), 'Select a WAV File');
            app.CurrentwavfileEditField.Value = app.file; %Will dispay file loaded in this box. 
            [app.AudioData1, app.Fs1] = audioread(fullfile(pathname, app.file));
            
            
            % Append the new file name to the fileList
            app.fileList{end+1, 1} = app.file;  % Just store the file name, not the path
            
            % Initialize the SNR value as empty for now
            app.snrList{end+1, 1} = ''; % Placeholder for SNR
                
            % Update UITable with the new file list
            app.UITable2.Data = [app.fileList, app.snrList];
       
        end

        % Callback function: opennoisefile
        function opennoisefileClicked(app, event)
            [app.file,path]= uigetfile('*.wav');
            [app.AudioData2, app.Fs2] = audioread(fullfile(path, app.file));
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %%%%%%%%%%%%% LOAD DATA
            t=(1:length(app.AudioData1))/app.Fs1; 
            
            f_highpass = 2500;
            [b2, a2] = butter(4,f_highpass/(app.Fs1/2),'high'); % High pass filter at 20 kHz
            x_filt_high = filtfilt(b2,a2,app.AudioData1) ; % filtfilt= filters twice in both directions to prevent distortion
            x_filt = abs(hilbert(x_filt_high));% Plots the envelope (removes the negative part of the axis)
            t_x_filt=(1:length(x_filt))/app.Fs1; 

            %%%%%%%%%%%% PLOT
            % Plot waveform
            plot(app.Waveformplot, app.AudioData1)
            axis(app.Waveformplot, 'tight');
            app.Waveformplot.YDir = 'normal';
            
            % spectrogram parameters
            fft_size = 1024;
            windowsize = 512;
            window = hann(512);
            overlap = 0.50;
            
            % Compute spectrogram
            [app.S, app.F, app.T] = spectrogram(app.AudioData1, window, floor(overlap*windowsize),fft_size,app.Fs1,'yaxis');
            % Plot the spectrogram
            imagesc(app.SpectrogramPlot, app.T,app.F/1000,(20*log10(abs(app.S))));
            axis(app.SpectrogramPlot, 'tight');
            app.SpectrogramPlot.YDir = 'normal';
            
        end

        % Button pushed function: CalculateSNRButton
        function CalculateSNRButtonPushed(app, event)
             %%%%%%%%%%%%%%%%%% SELECT START/END TIME & EXTRACT SNR
             
            fhv = app.SNRCalculatorUIFigure.HandleVisibility;        % Current status
            app.SNRCalculatorUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.SNRCalculatorUIFigure)       % Make fig current
    
            t=(1:length(app.AudioData1))/app.Fs1; 
            
            f_highpass = 2500;
            [b2, a2] = butter(4,f_highpass/(app.Fs1/2),'high'); % High pass filter at 20 kHz
            x_filt_high = filtfilt(b2,a2,app.AudioData1) ; % filtfilt= filters twice in both directions to prevent distortion
            x_filt = abs(hilbert(x_filt_high));% Plots the envelope (removes the negative part of the axis)
            t_x_filt=(1:length(x_filt))/app.Fs1; 

            time1= ginput(1)
            time2= ginput(1)
            s_filt = x_filt(round(time1(1)) : round(time2(1)));
            t_s_filt=(1:length(s_filt))/app.Fs1; 
            
            % Calculate Noise Only
            %filename_noise = 'noise_1438013011_20230221-134224-CATS-J1.wav';
            t_noise_filt=(1:length(app.AudioData2))/app.Fs2; 
            
            noise_filt_high = filtfilt(b2,a2,app.AudioData2) ; % filtfilt= filters twice in both directions to prevent distortion
            noise_filt = abs(hilbert(noise_filt_high));% Plots the envelope (removes the negative part of the axis)
            t_noise_filt=(1:length(noise_filt))/app.Fs2; 
            
            SNR_filt = 10*log10(var(s_filt)/var(noise_filt))
            
            rowIndex = find(cellfun('isempty', app.snrList), 1, 'first');
            
            % Store the calculated SNR value
            app.snrList{rowIndex, 1} = num2str(SNR_filt);  % Store SNR as string
            
            % Update UITable with the new file list and SNR values
            app.UITable2.Data = [app.fileList, app.snrList];
                                 
            app.SNRCalculatorUIFigure.HandleVisibility = fhv;        % return original state
        end

        % Button pushed function: SavecsvfileButton_2
        function SavecsvfileButton_2Pushed(app, event)
            data2= app.UITable2.Data;
            % Create a table from the fileList and snrList
            dataToSave = table(app.fileList, app.snrList, 'VariableNames', {'Filename', 'SNR'});
            writetable(dataToSave, 'E:/#DISSERTATION_final/CHAP 4 - PcTAG/Analysis/NF_SNRvalues.csv');
            % Notify user
            message = ["CSV file successfully saved!"];
            uialert(app.SNRCalculatorUIFigure, message, 'Success', 'Icon', 'success');

        end

        % Callback function: opendirectory
        function opendirectoryClicked(app, event)
            app.folderPath = uigetdir('', 'Select the folder containing the .wav file');
        end

        % Callback function
        function SNRCalculatorUIFigureSizeChanged(app, event)
            figsize = app.SNRCalculatorUIFigure.Position;
            % Define fixed size for the axes (keep them constant)
            axesWidth = 600;  % Fixed width of the axes
            axesHeight = 400; % Fixed height of the axes
            
            % Calculate the new position for the axes to keep them centered
            newLeft = (figSize(3) - axesWidth) / 2;  % Center horizontally
            newBottom = (figSize(4) - axesHeight) / 2; % Center vertically
            
            % Set the new position of the axes to keep them aligned and prevent resizing
            app.UIAxes.Position = [newLeft, newBottom, axesWidth, axesHeight];
            
            % Optionally, reset the axes limits if you want to ensure consistent scaling
            % app.UIAxes.XLim = [0 10];  % Set x-axis limits if needed
            % app.UIAxes.YLim = [-1 1];  % Set y-axis limits if needed
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SNRCalculatorUIFigure = uifigure('Visible', 'off');
            app.SNRCalculatorUIFigure.Name = 'SNR Calculator';
            app.Toolbar = uitoolbar(app.SNRCalculatorUIFigure);
            app.Toolbar.Tag = 'SNR Calculator ';
            app.opendirectory = uipushtool(app.Toolbar);
            app.opendirectory.Tooltip = {'Open Directory folder'};
            app.opendirectory.ClickedCallback = createCallbackFcn(app, @opendirectoryClicked, true);
            app.opendirectory.Icon = fullfile(pathToMLAPP, '2_directory.png');
            app.openwavfile = uipushtool(app.Toolbar);
            app.openwavfile.Tag = '.wav file';
            app.openwavfile.Tooltip = {'Open wav file'};
            app.openwavfile.ClickedCallback = createCallbackFcn(app, @openwavfileClicked, true);
            app.openwavfile.Icon = fullfile(pathToMLAPP, '1_wavfileicon.png');
            app.opennoisefile = uipushtool(app.Toolbar);
            app.opennoisefile.Tooltip = {'Open noise file'};
            app.opennoisefile.ClickedCallback = createCallbackFcn(app, @opennoisefileClicked, true);
            app.opennoisefile.Icon = fullfile(pathToMLAPP, '3_noise.jpg');
            app.SpectrogramPlot = uiaxes(app.SNRCalculatorUIFigure);
            title(app.SpectrogramPlot, 'Spectrogram')
            xlabel(app.SpectrogramPlot, 'Time')
            ylabel(app.SpectrogramPlot, 'Frequency')
            zlabel(app.SpectrogramPlot, 'Z')
            app.SpectrogramPlot.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.Waveformplot = uiaxes(app.SNRCalculatorUIFigure);
            title(app.Waveformplot, 'Waveform')
            ylabel(app.Waveformplot, 'Amplitude')
            zlabel(app.Waveformplot, 'Z')
            app.Waveformplot.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.Waveformplot.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.PlotButton = uibutton(app.SNRCalculatorUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CurrentwavfileEditFieldLabel = uilabel(app.SNRCalculatorUIFigure);
            app.CurrentwavfileEditFieldLabel.Text = 'Current .wav file';
            app.CurrentwavfileEditField = uieditfield(app.SNRCalculatorUIFigure, 'text');
            app.SNRValueLogLabel = uilabel(app.SNRCalculatorUIFigure);
            app.SNRValueLogLabel.Text = 'SNR Value Log';
            app.UITable2 = uitable(app.SNRCalculatorUIFigure);
            app.UITable2.ColumnName = {'Filename'; 'SNR'};
            app.UITable2.RowName = {};
            app.SavecsvfileButton_2 = uibutton(app.SNRCalculatorUIFigure, 'push');
            app.SavecsvfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavecsvfileButton_2Pushed, true);
            app.SavecsvfileButton_2.Text = 'Save .csv file';
            app.CalculateSNRButton = uibutton(app.SNRCalculatorUIFigure, 'push');
            app.CalculateSNRButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSNRButtonPushed, true);
            app.CalculateSNRButton.Text = 'Calculate SNR';
            % Show the figure after all components are created
            app.SNRCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculate_SNR_nonfocal_tagdata_DTAG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SNRCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SNRCalculatorUIFigure)
        end
    end
end